classdef OrientationAnalysis < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        IntensityPlotButton  matlab.ui.control.Button
        ExportDataButton     matlab.ui.control.Button
        ReadBrushButton      matlab.ui.control.Button
        UIAxes               matlab.ui.control.UIAxes
        ClearallButton       matlab.ui.control.Button
        DeleteRowButton      matlab.ui.control.Button
        OirentationButton    matlab.ui.control.Button
        UITable              matlab.ui.control.Table
    end


    properties (Access = public)
        Parent % handles of parent
        CurrentFig
        Data_Brush
        Data_2DSFG % StructureData Class
        Response_V  
        SelectedRow
        IntGrid
        N_Brished % Description
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, varargin)
            % if called from other GUI, assign parent handle
            if nargin > 1
                AssignParent(app,varargin)
                app.Data_2DSFG = app.Parent.Data_2DSFG;
            else
                disp('Running Orientation Analysis in the stand alone mode...')
            end
            
            % initialize the UIAxes and Table
            ClearallButtonPushed(app,'')
        end

        % Button pushed function: IntensityPlotButton
        function IntensityPlotButtonPushed(app, event)
            app.Data_2DSFG = app.Parent.Data_2DSFG;
            
            COSMOSS_Input = app.Parent.Parse_GUI;
            COSMOSS_Input.Lineshape = 'No Conv';
            COSMOSS_Input.PlotNorm  = 1;
            CVL = Conv2D(app.Data_2DSFG.Response.IntGrid,COSMOSS_Input);
            CVL.FilesName = 'Response intensity plot'; % pass filesname for figure title
            
            app.CurrentFig = figure;
            hAx = axes('Parent',app.CurrentFig);
            Plot2D(hAx,CVL,COSMOSS_Input,'2DSFG Int');
            hAx.Color = [0,0,1];
            colormap(hAx,'hot')
            caxis(hAx,[0,1])
        end

        % Button pushed function: ExportDataButton
        function ExportDataButtonPushed(app, event)
            Data_Orient.Data_2DSFG = app.Data_2DSFG;
            Data_Orient.Data_Brush = app.Data_Brush;
            Data_Orient.Response_V = app.Response_V;
            Data_Orient.Data_Table = app.UITable.Data;
            
            assignin('base','Data_Orient',Data_Orient)
            disp('Updated Data_Orient exported!')
        end

        % Button pushed function: ReadBrushButton
        function ReadBrushButtonPushed(app, event)
            N = app.N_Brished + 1; % update counter
            app.N_Brished = N;
            
            % Read brush data from the Intensity Plot
            hF = app.CurrentFig;
            hS = findobj(hF,'Type','Scatter');
            hAx = findobj(hF,'Type','Axes');
            app.UIAxes.XLim = hAx.XLim;
            app.UIAxes.YLim = hAx.YLim;
            
            BrushedIdx = logical(hS.BrushData);
            FreqSub    = [hS.YData(BrushedIdx)',hS.XData(BrushedIdx)']; % [Pump,Probe]
            SparseMax  = app.Data_2DSFG.Response.SparseMax;
            FreqInd    = sub2ind([SparseMax,SparseMax],FreqSub(:,1),FreqSub(:,2));
            
            % Update the Brushed response
            Beta =       app.Data_2DSFG.Response.Beta.R1(FreqInd,:);
            Beta = [Beta;app.Data_2DSFG.Response.Beta.R2(FreqInd,:);];
            Beta = [Beta;app.Data_2DSFG.Response.Beta.R3(FreqInd,:);];
            Beta = [Beta;app.Data_2DSFG.Response.Beta.NR1(FreqInd,:);];
            Beta = [Beta;app.Data_2DSFG.Response.Beta.NR2(FreqInd,:);];
            Beta = [Beta;app.Data_2DSFG.Response.Beta.NR3(FreqInd,:);];
            Beta = sum(Beta,1)';
            app.Response_V(:,N)= Beta;
            
            % update new info of table row
            Int  = full(sqrt(sum(Beta.^2)));
            TableRow = {true,sprintf('%3.2e',Int),1};
            BrushedColor = app.UIAxes.ColorOrder(N,:);
            
            % Update the UIAxes on GUI
            hold(app.UIAxes,'on')
                hScatter = scatter(app.UIAxes,...
                                   FreqSub(:,2),FreqSub(:,1),...
                                   'filled',...
                                   'MarkerFaceColor',BrushedColor);
            hold(app.UIAxes,'off')
            
            % export Data_Brush to app and update the UITable
            app.Data_Brush(N).BrushedIdx = BrushedIdx;
            app.Data_Brush(N).FreqSub    = FreqSub;
            app.Data_Brush(N).FreqInd    = FreqInd;
            app.Data_Brush(N).Color      = BrushedColor;
            app.Data_Brush(N).hScatter   = hScatter;
            app.Data_Brush(N).TableRow   = TableRow;
            Update_OrientTable(app)
        end

        % Button pushed function: ClearallButton
        function ClearallButtonPushed(app, event)
            % Reset other variable
            app.N_Brished  = 0;
            app.Response_V = [];
            
            % clean up Data_Brush
            app.Data_Brush = [];
            app.Data_Brush.BrushedIdx = [];
            app.Data_Brush.FreqSub    = [];
            app.Data_Brush.FreqInd    = [];
            app.Data_Brush.Color      = [1,1,1];
            app.Data_Brush.hScatter   = [];
            app.Data_Brush.TableRow   = {'','',''};
            Update_OrientTable(app)
            
            % make default figure
            cla(app.UIAxes)
            COSMOSS_Input = app.Parent.Parse_GUI;
            COSMOSS_Input.PlotCursor = 0;
            COSMOSS_Input.Lineshape = 'Lorentzian';
            COSMOSS_Input.CMAP_Index = 'JetWhiteWide';
            
            CVL = Conv2D(app.Data_2DSFG.SpectraGrid,COSMOSS_Input);
            CVL.FilesName = app.Parent.Structure.FilesName; % pass filesname for figure title
            Plot2D(app.UIAxes,CVL,COSMOSS_Input,app.Data_2DSFG.Response.SpecType);
            
            F_Array = COSMOSS_Input.F_Min:COSMOSS_Input.F_Max;
            app.UIAxes.XLim = [F_Array(1),F_Array(end)];
            app.UIAxes.YLim = [F_Array(1),F_Array(end)];
            app.UIAxes.ColorOrderIndex = 1;
        end

        % Button pushed function: DeleteRowButton
        function DeleteRowButtonPushed(app, event)
            Delete_Ind = app.SelectedRow;
            % update counter
            app.N_Brished = app.N_Brished - length(Delete_Ind);
            % delete scatter plot obj
            delete(app.Data_Brush(Delete_Ind).hScatter)
            % update Data_Brush
            app.Data_Brush(Delete_Ind) = [];
            Update_OrientTable(app)
        end

        % Button pushed function: OirentationButton
        function OirentationButtonPushed(app, event)
            COSMOSS_Input = app.Parent.Parse_GUI;
            Orientation_Input.SpecType = '2DSFG';
            Orientation_Input.N_Grid   = 18;
            EJLR_T = EJLR_Tensor(COSMOSS_Input,Orientation_Input);
            
            %Selection_Ind = app.UITable.Data{:,1};
            Selection_Ind = 4;
            app.IntGrid = EJLR_T * app.Response_V(:,Selection_Ind);
            
            % visulize
            N_Grid     = Orientation_Input.N_Grid;
            V = reshape(app.IntGrid,2*N_Grid,N_Grid,2*N_Grid);
            
            X = linspace(0,360,N_Grid*2)';
            Y = linspace(0,180,N_Grid)';
            Z = V(:,:,1);
            figure
            contour(Y,X,Z,20)
        end

        % Cell selection callback: UITable
        function UITableCellSelection(app, event)
            app.SelectedRow = event.Indices(:,1);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'Orientation Analysis';
            app.IntensityPlotButton = uibutton(app.UIFigure, 'push');
            app.IntensityPlotButton.ButtonPushedFcn = createCallbackFcn(app, @IntensityPlotButtonPushed, true);
            app.IntensityPlotButton.Text = 'Intensity Plot';
            app.ExportDataButton = uibutton(app.UIFigure, 'push');
            app.ExportDataButton.ButtonPushedFcn = createCallbackFcn(app, @ExportDataButtonPushed, true);
            app.ExportDataButton.Text = 'Export Data';
            app.ReadBrushButton = uibutton(app.UIFigure, 'push');
            app.ReadBrushButton.ButtonPushedFcn = createCallbackFcn(app, @ReadBrushButtonPushed, true);
            app.ReadBrushButton.Text = 'Read Brush';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Regio of selection')
            xlabel(app.UIAxes, 'Probe (cm^{-1})')
            ylabel(app.UIAxes, 'Pump (cm^{-1})')
            app.ClearallButton = uibutton(app.UIFigure, 'push');
            app.ClearallButton.ButtonPushedFcn = createCallbackFcn(app, @ClearallButtonPushed, true);
            app.ClearallButton.Text = 'Clear all';
            app.DeleteRowButton = uibutton(app.UIFigure, 'push');
            app.DeleteRowButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteRowButtonPushed, true);
            app.DeleteRowButton.Text = 'Delete Row';
            app.OirentationButton = uibutton(app.UIFigure, 'push');
            app.OirentationButton.ButtonPushedFcn = createCallbackFcn(app, @OirentationButtonPushed, true);
            app.OirentationButton.Text = 'Oirentation';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'};
            app.UITable.RowName = {};
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @UITableCellSelection, true);
        end
    end

    methods (Access = public)

        % Construct app
        function app = OrientationAnalysis(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end